classdef Matlab_App_BP_PZ_USP_TF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TransferFunctionButton    matlab.ui.control.Button
        PoleZerodiagramButton     matlab.ui.control.Button
        UnitStepResponseButton    matlab.ui.control.Button
        BodePlotButton            matlab.ui.control.Button
        TransferFunctionTextArea  matlab.ui.control.TextArea
        DampingRatioSlider        matlab.ui.control.Slider
        DampingRatioLabel         matlab.ui.control.Label
        GaininKSlider             matlab.ui.control.Slider
        GaininKSliderLabel        matlab.ui.control.Label
        NaturalfrequencyOscillationSlider  matlab.ui.control.Slider
        NaturalfrequencyOscillationLabel  matlab.ui.control.Label
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function TransferFunctionEditFieldValueChanging(app, event)

        end

        % Value changing function: TransferFunctionTextArea
        function TransferFunctionTextAreaValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: TransferFunctionTextArea
        function TransferFunctionTextAreaValueChanged(app, event)
        
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)
            
        end

        % Value changed function: DampingRatioSlider
        function DampingRatioSliderValueChanged(app, event)
            D_Value = app.DampingRatioSlider.Value;   
        end

        % Value changed function: GaininKSlider
        function GaininKSliderValueChanged(app, event)
            K_Value = app.GaininKSlider.Value ; 
        end

        % Value changed function: NaturalfrequencyOscillationSlider
        function NaturalfrequencyOscillationSliderValueChanged(app, event)
            Wn_Value = app.NaturalfrequencyOscillationSlider.Value;
        end

        % Button pushed function: BodePlotButton
        function BodePlotButtonPushed(app, event)
        
            K = app.GaininKSlider.Value;
            wn = app.NaturalfrequencyOscillationSlider.Value;
            delta = app.DampingRatioSlider.Value;
        
            % Define the transfer function
            G = tf(K*wn^2, [1 2*delta*wn wn^2]);
        
            % Generate the Bode plot using bode()
            [mag, phase, w] = bode(G);

            mag = squeeze(mag)
            phase = squeeze(phase)
            
            % Plot magnitude (dB) in semilogarithmic scale

            semilogx(app.UIAxes_3, w, 20*log10(mag));
            hold(app.UIAxes_3, 'off');
        
            % Plot phase (degrees) (optional)
            semilogx(app.UIAxes_5, w, phase );
            hold(app.UIAxes_5, 'off');
         
        end

        % Button pushed function: UnitStepResponseButton
        function UnitStepResponseButtonPushed(app, event)
            K = app.GaininKSlider.Value;
            wn = app.NaturalfrequencyOscillationSlider.Value;
            delta = app.DampingRatioSlider.Value;

            G = tf(K*wn^2, [1 2*delta*wn wn^2]);

            [y_t, t] = step(G);


            %t = 0:0.01:10;
            %exp_term = exp((-1)*(delta)*(wn)*(t)) ; 
            %sqrt_term = sqrt(1-(delta)^2) ; 
            %wd = wn*sqrt_term ; 
            %phi = atan(sqrt_term/delta) ; 

            % y_t = K*(1-(exp_term./sqrt_term).*sin(wd*t + phi)) ; 

            
            plot(app.UIAxes , t , y_t) ;
            hold(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: PoleZerodiagramButton
        function PoleZerodiagramButtonPushed(app, event)
            K = app.GaininKSlider.Value;
            wn = app.NaturalfrequencyOscillationSlider.Value;
            delta = app.DampingRatioSlider.Value;

            G = tf(K*wn^2, [1 2*delta*wn wn^2]);
            
            [p, z] = pzmap(G);
            
            
            plot(app.UIAxes_4, real(p), imag(p), 'bx', real(z), imag(z), 'ro');
            hold(app.UIAxes_4, 'on');
            
            hold(app.UIAxes_4, 'off');

        end

        % Button pushed function: TransferFunctionButton
        function TransferFunctionButtonPushed(app, event)
        
            K = app.GaininKSlider.Value;
            wn = app.NaturalfrequencyOscillationSlider.Value;
            delta = app.DampingRatioSlider.Value;
        
            G = tf(K*wn^2, [1 2*delta*wn wn^2]);
    
            [num, den] = tfdata(G, 'v');
        
            numStr = poly2str(num, 's');
            denStr = poly2str(den, 's');
        
            numStr = strrep(numStr, 's^1', 's');
            denStr = strrep(denStr, 's^1', 's');
        
            tfString = ['G(s) =' , numStr , '/' , denStr];
        
            app.TransferFunctionTextArea.Value = tfString; 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Unit Step Response')
            xlabel(app.UIAxes, 't (in sec)')
            ylabel(app.UIAxes, 'y(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Bode Plot ')
            xlabel(app.UIAxes_3, '\omega (in rad/s)')
            ylabel(app.UIAxes_3, 'Magnitude (in dB)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Pole - Zero diagram')
            xlabel(app.UIAxes_4, 'Real Axis ')
            ylabel(app.UIAxes_4, 'Imaginary Axis')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Bode Plot ')
            xlabel(app.UIAxes_5, '\omega (in rad/s)')
            ylabel(app.UIAxes_5, 'Phase (in deg)')
            zlabel(app.UIAxes_5, 'Z')
            app.NaturalfrequencyOscillationLabel = uilabel(app.UIFigure);
            app.NaturalfrequencyOscillationLabel.Text = {'Natural '; 'frequency '; 'Oscillation'};
            app.NaturalfrequencyOscillationSlider = uislider(app.UIFigure);
            app.NaturalfrequencyOscillationSlider.Limits = [0 10];
            app.NaturalfrequencyOscillationSlider.ValueChangedFcn = createCallbackFcn(app, @NaturalfrequencyOscillationSliderValueChanged, true);
            app.GaininKSliderLabel = uilabel(app.UIFigure);
            app.GaininKSliderLabel.Text = 'Gain ( in K)';
            app.GaininKSlider = uislider(app.UIFigure);
            app.GaininKSlider.Limits = [0 10];
            app.GaininKSlider.ValueChangedFcn = createCallbackFcn(app, @GaininKSliderValueChanged, true);
            app.DampingRatioLabel = uilabel(app.UIFigure);
            app.DampingRatioLabel.Text = {'Damping'; ' Ratio'};
            app.DampingRatioSlider = uislider(app.UIFigure);
            app.DampingRatioSlider.Limits = [0 5];
            app.DampingRatioSlider.ValueChangedFcn = createCallbackFcn(app, @DampingRatioSliderValueChanged, true);
            app.TransferFunctionTextArea = uitextarea(app.UIFigure);
            app.TransferFunctionTextArea.ValueChangedFcn = createCallbackFcn(app, @TransferFunctionTextAreaValueChanged, true);
            app.TransferFunctionTextArea.ValueChangingFcn = createCallbackFcn(app, @TransferFunctionTextAreaValueChanging, true);
            app.BodePlotButton = uibutton(app.UIFigure, 'push');
            app.BodePlotButton.ButtonPushedFcn = createCallbackFcn(app, @BodePlotButtonPushed, true);
            app.BodePlotButton.Text = 'Bode Plot ';
            app.UnitStepResponseButton = uibutton(app.UIFigure, 'push');
            app.UnitStepResponseButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepResponseButtonPushed, true);
            app.UnitStepResponseButton.Text = 'Unit Step Response';
            app.PoleZerodiagramButton = uibutton(app.UIFigure, 'push');
            app.PoleZerodiagramButton.ButtonPushedFcn = createCallbackFcn(app, @PoleZerodiagramButtonPushed, true);
            app.PoleZerodiagramButton.Text = 'Pole Zero diagram';
            app.TransferFunctionButton = uibutton(app.UIFigure, 'push');
            app.TransferFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @TransferFunctionButtonPushed, true);
            app.TransferFunctionButton.Text = 'Transfer Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_App_BP_PZ_USP_TF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end